classdef BrainRuler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        InputBoxPanel        matlab.ui.container.Panel
        HorizontalLabel      matlab.ui.control.Label
        HorizontalEditField  matlab.ui.control.NumericEditField
        VerticalLabel        matlab.ui.control.Label
        VerticalEditField    matlab.ui.control.NumericEditField
        VerticalPanel        matlab.ui.container.Panel
        FzEditFieldLabel     matlab.ui.control.Label
        FzEditField          matlab.ui.control.NumericEditField
        CzEditFieldLabel     matlab.ui.control.Label
        CzEditField          matlab.ui.control.NumericEditField
        PzEditFieldLabel     matlab.ui.control.Label
        PzEditField          matlab.ui.control.NumericEditField
        HorizontalPanel      matlab.ui.container.Panel
        C3EditField_2Label   matlab.ui.control.Label
        C3EditField_2        matlab.ui.control.NumericEditField
        CzEditField_2Label   matlab.ui.control.Label
        CzEditField_2        matlab.ui.control.NumericEditField
        C4EditField_2Label   matlab.ui.control.Label
        C4EditField_2        matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: VerticalEditField
        function VerticalEditFieldValueChanged(app, event)
            value = app.VerticalEditField.Value;
            Cz_ver = value/2;
            Fz_ver = value*0.3;
            Pz_ver = value*0.7;
            app.CzEditField.Value = Cz_ver;
            app.FzEditField.Value = Fz_ver;
            app.PzEditField.Value = Pz_ver;
        end

        % Value changed function: HorizontalEditField
        function HorizontalEditFieldValueChanged(app, event)
            value = app.HorizontalEditField.Value;
            Cz_hor = value/2;
            C3_hor = value*0.3;
            C4_hor = value*0.7;
            app.CzEditField_2.Value = Cz_hor;
            app.C3EditField_2.Value = C3_hor;
            app.C4EditField_2.Value = C4_hor;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.InputBoxPanel = uipanel(app.UIFigure);
            app.InputBoxPanel.Title = 'Input Box';
            app.HorizontalLabel = uilabel(app.InputBoxPanel);
            app.HorizontalLabel.Text = 'Horizontal / 水平方向';
            app.HorizontalEditField = uieditfield(app.InputBoxPanel, 'numeric');
            app.HorizontalEditField.ValueChangedFcn = createCallbackFcn(app, @HorizontalEditFieldValueChanged, true);
            app.VerticalLabel = uilabel(app.InputBoxPanel);
            app.VerticalLabel.Text = 'Vertical / 垂直方向';
            app.VerticalEditField = uieditfield(app.InputBoxPanel, 'numeric');
            app.VerticalEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalEditFieldValueChanged, true);
            app.VerticalPanel = uipanel(app.UIFigure);
            app.VerticalPanel.Title = 'Vertical / 垂直方法';
            app.FzEditFieldLabel = uilabel(app.VerticalPanel);
            app.FzEditFieldLabel.Text = 'Fz';
            app.FzEditField = uieditfield(app.VerticalPanel, 'numeric');
            app.FzEditField.Editable = 'off';
            app.CzEditFieldLabel = uilabel(app.VerticalPanel);
            app.CzEditFieldLabel.Text = 'Cz';
            app.CzEditField = uieditfield(app.VerticalPanel, 'numeric');
            app.CzEditField.Editable = 'off';
            app.PzEditFieldLabel = uilabel(app.VerticalPanel);
            app.PzEditFieldLabel.Text = 'Pz';
            app.PzEditField = uieditfield(app.VerticalPanel, 'numeric');
            app.PzEditField.Editable = 'off';
            app.HorizontalPanel = uipanel(app.UIFigure);
            app.HorizontalPanel.Title = 'Horizontal / 水平方向';
            app.C3EditField_2Label = uilabel(app.HorizontalPanel);
            app.C3EditField_2Label.Text = 'C3';
            app.C3EditField_2 = uieditfield(app.HorizontalPanel, 'numeric');
            app.C3EditField_2.Editable = 'off';
            app.CzEditField_2Label = uilabel(app.HorizontalPanel);
            app.CzEditField_2Label.Text = 'Cz';
            app.CzEditField_2 = uieditfield(app.HorizontalPanel, 'numeric');
            app.CzEditField_2.Editable = 'off';
            app.C4EditField_2Label = uilabel(app.HorizontalPanel);
            app.C4EditField_2Label.Text = 'C4';
            app.C4EditField_2 = uieditfield(app.HorizontalPanel, 'numeric');
            app.C4EditField_2.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BrainRuler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end